classdef CalcApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IceCubePowerSimUIFigure        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RunningSimLabel                matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        CustomInputTab                 matlab.ui.container.Tab
        UpdateParametersButton         matlab.ui.control.Button
        RoverParametersPanel_2         matlab.ui.container.Panel
        BevelGearEfficiencySpinner_2   matlab.ui.control.Spinner
        BevelGearEfficiencySpinner_2Label  matlab.ui.control.Label
        TotalMassKgSpinner_2           matlab.ui.control.Spinner
        TotalMassKgSpinner_2Label      matlab.ui.control.Label
        TireTractionPanel              matlab.ui.container.Panel
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ELabel                         matlab.ui.control.Label
        DLabel                         matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        BLabel                         matlab.ui.control.Label
        LevelDropDown                  matlab.ui.control.DropDown
        LevelDropDownLabel             matlab.ui.control.Label
        EnvironmentParametersPanel     matlab.ui.container.Panel
        HeadWindmsSlider               matlab.ui.control.Slider
        HeadWindmsSliderLabel          matlab.ui.control.Label
        InclinedegSlider               matlab.ui.control.Slider
        InclinedegLabel                matlab.ui.control.Label
        StepInputTab                   matlab.ui.container.Tab
        UpdateParametersButton_2       matlab.ui.control.Button
        RoverParametersPanel           matlab.ui.container.Panel
        BevelGearEfficiencySpinner     matlab.ui.control.Spinner
        BevelGearEfficiencyLabel       matlab.ui.control.Label
        TotalMassKgSpinner             matlab.ui.control.Spinner
        TotalMassKgLabel               matlab.ui.control.Label
        EnvironmentParametersPanel_2   matlab.ui.container.Panel
        HeadWindmsSlider_2             matlab.ui.control.Slider
        HeadWindmsSlider_2Label        matlab.ui.control.Label
        InclinedegSlider_2             matlab.ui.control.Slider
        InclinedegSlider_2Label        matlab.ui.control.Label
        PWMParametersPanel             matlab.ui.container.Panel
        StepTimesSlider                matlab.ui.control.Slider
        StepTimesLabel                 matlab.ui.control.Label
        FinalDutyCycleSpinner          matlab.ui.control.Spinner
        FinalDutyCycleSpinnerLabel     matlab.ui.control.Label
        InitialDutyCylceSpinner        matlab.ui.control.Spinner
        InitialDutyCylceLabel          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ComparewithLastOutputSwitch    matlab.ui.control.Switch
        ComparewithLastOutputSwitchLabel  matlab.ui.control.Label
        ShowDutyCycleInputSwitch       matlab.ui.control.Switch
        ShowDutyCycleInputSwitchLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        DynamicsTab                    matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        CurrentDrawTab                 matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        PowerUsageTab                  matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        IceCubePowerSimsLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Incline         % Terrain Incline (deg)
        stepIncline
        HeadWind        % Wind speed (m/s)
        stepHeadWind
        Time            % Time array from sim (s)
        Time2
        Velocity        % Velocity array from sim (m/s)
        Velocity2
        Current         % Current array from sim (A)
        Current2
        Power           % Electrical power array from sim (W)
        Power2
        DutyCycle       % Duty cycle input to sim(%)
        DutyCycle2
        showDC          % Boolean to show input signal on graphs
        saveData        % Boolean to save the last sim data for comparison
        firstSim        % Boolean to determine if the app doesn't have past data
        MagicFormula    % Magic formula constants
        Mass            % Total rover mass (Kg)
        stepMass
        BgEfficiency    % Bevel gear efficiency
        stepBgEffciency
        stepDCI         % Initial duty cycle before step
        stepDCF         % Final duty cycle after step
        stepT           % Time of step change (s)
        
        Sim = 'ScriptingSim.slx'; % Simulation name
    end
    
    methods (Access = private)
        
        % Displays graphs when changes are made
        function resetGraphs(app)
            % Clear graphs
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            % Plot output
            yyaxis(app.UIAxes,'left')
            plot(app.UIAxes,app.Time,app.Velocity);
            yyaxis(app.UIAxes_2,'left')
            plot(app.UIAxes_2,app.Time,app.Current);
            yyaxis(app.UIAxes_3,'left')
            plot(app.UIAxes_3,app.Time,app.Power);
            
             % Show past outputs if needed
            if app.saveData
                if ~app.firstSim
                    hold(app.UIAxes,'on');
                    hold(app.UIAxes_2,'on');
                    hold(app.UIAxes_3,'on');
                    plot(app.UIAxes,app.Time2,app.Velocity2,'g');
                    plot(app.UIAxes_2,app.Time2,app.Current2,'g');
                    plot(app.UIAxes_3,app.Time2,app.Power2,'g');
                    hold(app.UIAxes,'off');
                    hold(app.UIAxes_2,'off');
                    hold(app.UIAxes_3,'off');
                end
            end
            
            % Label secondary axes
            yyaxis(app.UIAxes,'right');
            ylabel(app.UIAxes,'Duty Cycle');
            ylim(app.UIAxes,[0,1]);
            yyaxis(app.UIAxes_2,'right');
            ylabel(app.UIAxes_2,'Duty Cycle');
            ylim(app.UIAxes_2,[0,1]);
            yyaxis(app.UIAxes_3,'right')
            ylabel(app.UIAxes_3,'Duty Cycle');
            ylim(app.UIAxes_3,[0,1]);
            
            % Show input signal if needed
            if app.showDC
                plot(app.UIAxes,app.Time,app.DutyCycle);
                plot(app.UIAxes_2,app.Time,app.DutyCycle);
                plot(app.UIAxes_3,app.Time,app.DutyCycle);
                if app.saveData
                    if ~app.firstSim
                        hold(app.UIAxes,'on');
                        hold(app.UIAxes_2,'on');
                        hold(app.UIAxes_3,'on');
                        plot(app.UIAxes,app.Time2,app.DutyCycle2);
                        plot(app.UIAxes_2,app.Time2,app.DutyCycle2);
                        plot(app.UIAxes_3,app.Time2,app.DutyCycle2);
                        hold(app.UIAxes,'off');
                        hold(app.UIAxes_2,'off');
                        hold(app.UIAxes_3,'off');
                    end
                end
            end
            
        end
        
        % Run simulink
        function startSim(app)
            simOut = sim(app.Sim,'CaptureErrors','on');
            app.Time = simOut.get('tout');
            app.DutyCycle = simOut.get('yout').get('Duty Cycle').Values.Data;
            app.Velocity = simOut.get('yout').get('Velocity').Values.Data;
            app.Power = simOut.get('yout').get('Electrical Power').Values.Data;
            app.Current = simOut.get('yout').get('Current').Values.Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Prep app view
            app.RunningSimLabel.Visible = false;
            app.showDC = false;
            app.firstSim = true;
            
            % Read in variables from base workspace
            app.Incline = evalin('base','incline');
            app.HeadWind = evalin('base','headWindSpeed');
            app.MagicFormula = evalin('base','magicCoefficients');
            app.Mass = evalin('base','roverMass');
            app.BgEfficiency = evalin('base','bevelEfficiency');
            app.stepDCI = evalin('base','dci');
            app.stepDCF = evalin('base','dcf');
            app.stepT = evalin('base','stepTime');
            app.stepIncline = app.Incline;
            app.stepHeadWind = app.HeadWind;
            app.stepMass = app.Mass;
            app.stepBgEffciency = app.BgEfficiency;
            
            % Initialize graph outputs
            app.startSim();
            app.resetGraphs();
            
            % Initialize label values
            app.Label.Text = string(app.MagicFormula(1));
            app.Label_2.Text = string(app.MagicFormula(2));
            app.Label_3.Text = string(app.MagicFormula(3));
            app.Label_4.Text = string(app.MagicFormula(4));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IceCubePowerSimUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {604, 604};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {252, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
            % Show running sim message
            app.RunningSimLabel.Visible = true;
            
            % Save last graph data
            app.firstSim = false;
            app.Time2 = app.Time;
            app.DutyCycle2 = app.DutyCycle;
            app.Velocity2 = app.Velocity;
            app.Current2 = app.Current;
            app.Power2 = app.Power;
            
            % Load updated variables into base workspace
            assignin('base','incline',app.Incline);
            assignin('base','headWindSpeed',app.HeadWind);
            assignin('base','magicCoefficients',app.MagicFormula);
            assignin('base','roverMass',app.Mass);
            assignin('base','bevelEfficiency',app.BgEfficiency);
            
            % Update graph outputs
            app.Sim = 'ScriptingSim';
            app.startSim();
            app.resetGraphs();
            
            % Remove message
            app.RunningSimLabel.Visible = false;
        end

        % Value changed function: HeadWindmsSlider
        function HeadWindmsSliderValueChanged(app, event)
            % Save value from slider
            value = app.HeadWindmsSlider.Value;
            app.HeadWind = value;
        end

        % Value changed function: InclinedegSlider
        function InclinedegSliderValueChanged(app, event)
            % Save value from slider
            value = app.InclinedegSlider.Value;
            app.Incline = value;
        end

        % Value changed function: ShowDutyCycleInputSwitch
        function ShowDutyCycleInputSwitchValueChanged(app, event)
            value = app.ShowDutyCycleInputSwitch.Value;
            switch value
                case 'Off'
                    % Remove input signal from graphs
                    app.showDC = false;
                    app.resetGraphs();
                case 'On'
                    % Add input signal to graphs
                    app.showDC = true;
                    app.resetGraphs();
            end
        end

        % Value changed function: LevelDropDown
        function LevelDropDownValueChanged(app, event)
            % Save value from drop down bar
            value = app.LevelDropDown.Value;
            switch value
                case 'Highest'
                    app.MagicFormula = evalin('base','mf1');
                case 'High'
                    app.MagicFormula = evalin('base','mf2');
                case 'Low'
                    app.MagicFormula = evalin('base','mf3');
                case 'Lowest'
                    app.MagicFormula = evalin('base','mf4');
            end
            app.Label.Text = string(app.MagicFormula(1));
            app.Label_2.Text = string(app.MagicFormula(2));
            app.Label_3.Text = string(app.MagicFormula(3));
            app.Label_4.Text = string(app.MagicFormula(4));
        end

        % Value changed function: TotalMassKgSpinner
        function TotalMassKgSpinnerValueChanged(app, event)
            value = app.TotalMassKgSpinner.Value;
            app.Mass = value;
        end

        % Value changed function: BevelGearEfficiencySpinner
        function BevelGearEfficiencySpinnerValueChanged(app, event)
            value = app.BevelGearEfficiencySpinner.Value;
            app.BgEfficiency = value;
        end

        % Value changed function: InclinedegSlider_2
        function InclinedegSlider_2ValueChanged(app, event)
            value = app.InclinedegSlider_2.Value;
            app.stepIncline = value;
        end

        % Value changed function: HeadWindmsSlider_2
        function HeadWindmsSlider_2ValueChanged(app, event)
            value = app.HeadWindmsSlider_2.Value;
            app.stepHeadWind = value;
        end

        % Value changed function: StepTimesSlider
        function StepTimesSliderValueChanged(app, event)
            value = app.StepTimesSlider.Value;
            app.stepT = value;
        end

        % Value changed function: FinalDutyCycleSpinner
        function FinalDutyCycleSpinnerValueChanged(app, event)
            value = app.FinalDutyCycleSpinner.Value;
            app.stepDCF = value;
        end

        % Value changed function: InitialDutyCylceSpinner
        function InitialDutyCylceSpinnerValueChanged(app, event)
            value = app.InitialDutyCylceSpinner.Value;
            app.stepDCI = value;
        end

        % Value changed function: TotalMassKgSpinner_2
        function TotalMassKgSpinner_2ValueChanged(app, event)
            value = app.TotalMassKgSpinner_2.Value;
            app.stepMass = value;
        end

        % Value changed function: BevelGearEfficiencySpinner_2
        function BevelGearEfficiencySpinner_2ValueChanged(app, event)
            value = app.BevelGearEfficiencySpinner_2.Value;
            app.stepBgEffciency = value;
        end

        % Button pushed function: UpdateParametersButton_2
        function UpdateParametersButton_2Pushed(app, event)
            % Show running sim message
            app.RunningSimLabel.Visible = true;
            
            % Save last graph data
            app.firstSim = false;
            app.Time2 = app.Time;
            app.DutyCycle2 = app.DutyCycle;
            app.Velocity2 = app.Velocity;
            app.Current2 = app.Current;
            app.Power2 = app.Power;
            
            % Load updated variables into base workspace
            assignin('base','incline',app.stepIncline);
            assignin('base','headWindSpeed',app.stepHeadWind);
            assignin('base','magicCoefficients',evalin('base','mf1'));
            assignin('base','roverMass',app.stepMass);
            assignin('base','bevelEfficiency',app.stepBgEffciency);
            assignin('base','dcf',app.stepDCF);
            assignin('base','dci',app.stepDCI);
            assignin('base','stepTime',app.stepT);
            
            % Update graph outputs
            app.Sim = 'StepInputSim';
            app.startSim();
            app.resetGraphs();
            
            % Remove message
            app.RunningSimLabel.Visible = false;
        end

        % Value changed function: ComparewithLastOutputSwitch
        function ComparewithLastOutputSwitchValueChanged(app, event)
            value = app.ComparewithLastOutputSwitch.Value;
            switch value
                case 'Off'
                    % Remove input signal from graphs
                    app.saveData = false;
                    app.resetGraphs();
                case 'On'
                    % Add input signal to graphs
                    app.saveData = true;
                    app.resetGraphs();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IceCubePowerSimUIFigure = uifigure('Visible', 'off');
            app.IceCubePowerSimUIFigure.Name = 'Ice Cube PowerSim';
            app.IceCubePowerSimUIFigure.Icon = fullfile(pathToMLAPP, 'Blue.png');
            app.IceCubePowerSimUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.IceCubePowerSimUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.CustomInputTab = uitab(app.TabGroup2);
            app.CustomInputTab.Title = 'Custom Input';
            app.EnvironmentParametersPanel = uipanel(app.CustomInputTab);
            app.EnvironmentParametersPanel.Title = 'Environment Parameters';
            app.InclinedegLabel = uilabel(app.EnvironmentParametersPanel);
            app.InclinedegLabel.Text = {'Incline (deg.) '; ''};
            app.InclinedegSlider = uislider(app.EnvironmentParametersPanel);
            app.InclinedegSlider.Limits = [0 50];
            app.InclinedegSlider.ValueChangedFcn = createCallbackFcn(app, @InclinedegSliderValueChanged, true);
            app.HeadWindmsSliderLabel = uilabel(app.EnvironmentParametersPanel);
            app.HeadWindmsSliderLabel.Text = {'Head Wind (m/s)'; ''};
            app.HeadWindmsSlider = uislider(app.EnvironmentParametersPanel);
            app.HeadWindmsSlider.Limits = [0 10];
            app.HeadWindmsSlider.ValueChangedFcn = createCallbackFcn(app, @HeadWindmsSliderValueChanged, true);
            app.TireTractionPanel = uipanel(app.CustomInputTab);
            app.TireTractionPanel.Title = 'Tire Traction';
            app.LevelDropDownLabel = uilabel(app.TireTractionPanel);
            app.LevelDropDownLabel.Text = 'Level:';
            app.LevelDropDown = uidropdown(app.TireTractionPanel);
            app.LevelDropDown.Items = {'Highest', 'High', 'Low', 'Lowest'};
            app.LevelDropDown.ValueChangedFcn = createCallbackFcn(app, @LevelDropDownValueChanged, true);
            app.LevelDropDown.Value = 'Highest';
            app.BLabel = uilabel(app.TireTractionPanel);
            app.BLabel.Text = 'B:';
            app.CLabel = uilabel(app.TireTractionPanel);
            app.CLabel.Text = 'C:';
            app.DLabel = uilabel(app.TireTractionPanel);
            app.DLabel.Text = 'D:';
            app.ELabel = uilabel(app.TireTractionPanel);
            app.ELabel.Text = 'E:';
            app.Label = uilabel(app.TireTractionPanel);
            app.Label_2 = uilabel(app.TireTractionPanel);
            app.Label_3 = uilabel(app.TireTractionPanel);
            app.Label_4 = uilabel(app.TireTractionPanel);
            app.RoverParametersPanel_2 = uipanel(app.CustomInputTab);
            app.RoverParametersPanel_2.Title = 'Rover Parameters';
            app.TotalMassKgSpinner_2Label = uilabel(app.RoverParametersPanel_2);
            app.TotalMassKgSpinner_2Label.Text = 'Total Mass (Kg) ';
            app.TotalMassKgSpinner_2 = uispinner(app.RoverParametersPanel_2);
            app.TotalMassKgSpinner_2.Step = 5;
            app.TotalMassKgSpinner_2.Limits = [10 Inf];
            app.TotalMassKgSpinner_2.ValueChangedFcn = createCallbackFcn(app, @TotalMassKgSpinner_2ValueChanged, true);
            app.TotalMassKgSpinner_2.Value = 50;
            app.BevelGearEfficiencySpinner_2Label = uilabel(app.RoverParametersPanel_2);
            app.BevelGearEfficiencySpinner_2Label.Text = 'Bevel Gear Efficiency ';
            app.BevelGearEfficiencySpinner_2 = uispinner(app.RoverParametersPanel_2);
            app.BevelGearEfficiencySpinner_2.Step = 0.1;
            app.BevelGearEfficiencySpinner_2.Limits = [0 1];
            app.BevelGearEfficiencySpinner_2.ValueChangedFcn = createCallbackFcn(app, @BevelGearEfficiencySpinner_2ValueChanged, true);
            app.BevelGearEfficiencySpinner_2.Value = 0.8;
            app.UpdateParametersButton = uibutton(app.CustomInputTab, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters ;)';
            app.StepInputTab = uitab(app.TabGroup2);
            app.StepInputTab.Title = 'Step Input';
            app.PWMParametersPanel = uipanel(app.StepInputTab);
            app.PWMParametersPanel.Title = 'PWM Parameters';
            app.InitialDutyCylceLabel = uilabel(app.PWMParametersPanel);
            app.InitialDutyCylceLabel.Text = 'Initial Duty Cylce ';
            app.InitialDutyCylceSpinner = uispinner(app.PWMParametersPanel);
            app.InitialDutyCylceSpinner.Step = 0.1;
            app.InitialDutyCylceSpinner.Limits = [0 1];
            app.InitialDutyCylceSpinner.ValueChangedFcn = createCallbackFcn(app, @InitialDutyCylceSpinnerValueChanged, true);
            app.FinalDutyCycleSpinnerLabel = uilabel(app.PWMParametersPanel);
            app.FinalDutyCycleSpinnerLabel.Text = 'Final Duty Cycle';
            app.FinalDutyCycleSpinner = uispinner(app.PWMParametersPanel);
            app.FinalDutyCycleSpinner.Step = 0.1;
            app.FinalDutyCycleSpinner.Limits = [0 1];
            app.FinalDutyCycleSpinner.ValueChangedFcn = createCallbackFcn(app, @FinalDutyCycleSpinnerValueChanged, true);
            app.FinalDutyCycleSpinner.Value = 0.2;
            app.StepTimesLabel = uilabel(app.PWMParametersPanel);
            app.StepTimesLabel.Text = 'Step Time (s) ';
            app.StepTimesSlider = uislider(app.PWMParametersPanel);
            app.StepTimesSlider.Limits = [0 10];
            app.StepTimesSlider.MajorTicks = [0 5 10];
            app.StepTimesSlider.MajorTickLabels = {'0', '5', '10'};
            app.StepTimesSlider.ValueChangedFcn = createCallbackFcn(app, @StepTimesSliderValueChanged, true);
            app.StepTimesSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.EnvironmentParametersPanel_2 = uipanel(app.StepInputTab);
            app.EnvironmentParametersPanel_2.Title = 'Environment Parameters';
            app.InclinedegSlider_2Label = uilabel(app.EnvironmentParametersPanel_2);
            app.InclinedegSlider_2Label.Text = {'Incline (deg.) '; ''};
            app.InclinedegSlider_2 = uislider(app.EnvironmentParametersPanel_2);
            app.InclinedegSlider_2.Limits = [0 50];
            app.InclinedegSlider_2.ValueChangedFcn = createCallbackFcn(app, @InclinedegSlider_2ValueChanged, true);
            app.HeadWindmsSlider_2Label = uilabel(app.EnvironmentParametersPanel_2);
            app.HeadWindmsSlider_2Label.Text = {'Head Wind (m/s)'; ''};
            app.HeadWindmsSlider_2 = uislider(app.EnvironmentParametersPanel_2);
            app.HeadWindmsSlider_2.Limits = [0 10];
            app.HeadWindmsSlider_2.ValueChangedFcn = createCallbackFcn(app, @HeadWindmsSlider_2ValueChanged, true);
            app.RoverParametersPanel = uipanel(app.StepInputTab);
            app.RoverParametersPanel.Title = 'Rover Parameters';
            app.TotalMassKgLabel = uilabel(app.RoverParametersPanel);
            app.TotalMassKgLabel.Text = 'Total Mass (Kg) ';
            app.TotalMassKgSpinner = uispinner(app.RoverParametersPanel);
            app.TotalMassKgSpinner.Step = 5;
            app.TotalMassKgSpinner.Limits = [10 Inf];
            app.TotalMassKgSpinner.ValueChangedFcn = createCallbackFcn(app, @TotalMassKgSpinnerValueChanged, true);
            app.TotalMassKgSpinner.Value = 50;
            app.BevelGearEfficiencyLabel = uilabel(app.RoverParametersPanel);
            app.BevelGearEfficiencyLabel.Text = 'Bevel Gear Efficiency ';
            app.BevelGearEfficiencySpinner = uispinner(app.RoverParametersPanel);
            app.BevelGearEfficiencySpinner.Step = 0.1;
            app.BevelGearEfficiencySpinner.Limits = [0 1];
            app.BevelGearEfficiencySpinner.ValueChangedFcn = createCallbackFcn(app, @BevelGearEfficiencySpinnerValueChanged, true);
            app.BevelGearEfficiencySpinner.Value = 0.8;
            app.UpdateParametersButton_2 = uibutton(app.StepInputTab, 'push');
            app.UpdateParametersButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButton_2Pushed, true);
            app.UpdateParametersButton_2.Text = 'Update Parameters';
            app.RunningSimLabel = uilabel(app.LeftPanel);
            app.RunningSimLabel.Text = 'Running Sim...';
            app.RightPanel = uipanel(app.GridLayout);
            app.IceCubePowerSimsLabel = uilabel(app.RightPanel);
            app.IceCubePowerSimsLabel.Text = 'Ice Cube Power Sims';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DynamicsTab = uitab(app.TabGroup);
            app.DynamicsTab.Title = 'Dynamics';
            app.UIAxes = uiaxes(app.DynamicsTab);
            title(app.UIAxes, 'Rover Dynamics')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Velocity (m/s)')
            zlabel(app.UIAxes, 'Z')
            app.CurrentDrawTab = uitab(app.TabGroup);
            app.CurrentDrawTab.Title = 'Current Draw';
            app.UIAxes_2 = uiaxes(app.CurrentDrawTab);
            title(app.UIAxes_2, 'Rover Current Draw')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Current (A)')
            zlabel(app.UIAxes_2, 'Z')
            app.PowerUsageTab = uitab(app.TabGroup);
            app.PowerUsageTab.Title = 'Power Usage';
            app.UIAxes_3 = uiaxes(app.PowerUsageTab);
            title(app.UIAxes_3, 'Rover Power Usage')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Electrical Power (W)')
            zlabel(app.UIAxes_3, 'Z')
            app.ShowDutyCycleInputSwitchLabel = uilabel(app.RightPanel);
            app.ShowDutyCycleInputSwitchLabel.Text = 'Show Duty Cycle Input';
            app.ShowDutyCycleInputSwitch = uiswitch(app.RightPanel, 'slider');
            app.ShowDutyCycleInputSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowDutyCycleInputSwitchValueChanged, true);
            app.ComparewithLastOutputSwitchLabel = uilabel(app.RightPanel);
            app.ComparewithLastOutputSwitchLabel.Text = 'Compare with Last Output';
            app.ComparewithLastOutputSwitch = uiswitch(app.RightPanel, 'slider');
            app.ComparewithLastOutputSwitch.ValueChangedFcn = createCallbackFcn(app, @ComparewithLastOutputSwitchValueChanged, true);
            % Show the figure after all components are created
            app.IceCubePowerSimUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalcApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IceCubePowerSimUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IceCubePowerSimUIFigure)
        end
    end
end